classdef P1_7Equipo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        TabGroup                      matlab.ui.container.TabGroup
        OperacionesBsicasTab          matlab.ui.container.Tab
        GuardarImagenButton           matlab.ui.control.Button
        DeshacerButton                matlab.ui.control.Button
        CargarImagenButton            matlab.ui.control.Button
        LabelInfoProcesada            matlab.ui.control.Label
        LabelInfoOriginal             matlab.ui.control.Label
        AnlisisdepxelesPanel          matlab.ui.container.Panel
        CalcularDistanciasButton      matlab.ui.control.Button
        OperacionesdeintensidadPanel  matlab.ui.container.Panel
        SustraccinButton              matlab.ui.control.Button
        AdicinButton                  matlab.ui.control.Button
        InversinBinariaButton         matlab.ui.control.Button
        InversinFotogrficaButton      matlab.ui.control.Button
        TransformacionesgeomtricasPanel  matlab.ui.container.Panel
        MosaicoButton                 matlab.ui.control.Button
        EspejoButton                  matlab.ui.control.Button
        RotarImagenButton             matlab.ui.control.Button
        DesplazarImagenButton         matlab.ui.control.Button
        ModificarTamaoButton          matlab.ui.control.Button
        UIAxesProcesada               matlab.ui.control.UIAxes
        UIAxesOriginal                matlab.ui.control.UIAxes
        EcualizacinTab                matlab.ui.container.Tab
        DeshacerButton_2              matlab.ui.control.Button
        EcualizacinPanel              matlab.ui.container.Panel
        EcualizarButton               matlab.ui.control.Button
        ShrinkButton                  matlab.ui.control.Button
        StrecthButton                 matlab.ui.control.Button
        HistogramaButton              matlab.ui.control.Button
        GuardarImagenButton_2         matlab.ui.control.Button
        LabelInfoProcesada_2          matlab.ui.control.Label
        LabelInfoOriginal_2           matlab.ui.control.Label
        UIAxesHistogramaProcesada_2   matlab.ui.control.UIAxes
        UIAxesHistograma              matlab.ui.control.UIAxes
        UIAxesProcesada_2             matlab.ui.control.UIAxes
        UIAxesOriginal_2              matlab.ui.control.UIAxes
        UmbralizacinTab               matlab.ui.container.Tab
        GuardarImagenButton_3         matlab.ui.control.Button
        LabelInfoProcesada_3          matlab.ui.control.Label
        LabelInfoOriginal_3           matlab.ui.control.Label
        UIAxesProcesada_3             matlab.ui.control.UIAxes
        UIAxesOriginal_3              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
    ImagenOriginal
    ImagenProcesada
    HistorialOperaciones = {}
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ModificarTamaoButton
        function ModificarTamanioButtonPushed(app, event)
   if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar nuevo tamaño al usuario
    prompt = {'Nuevo ancho:', 'Nuevo alto:'};
    dlgtitle = 'Modificar tamaño de imagen';
    dims = [1 35];
    definput = {num2str(size(app.ImagenOriginal,2)), num2str(size(app.ImagenOriginal,1))};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    nuevoAncho = str2double(answer{1});
    nuevoAlto = str2double(answer{2});

    if isnan(nuevoAncho) || isnan(nuevoAlto) || nuevoAncho <= 0 || nuevoAlto <= 0
        uialert(app.UIFigure, 'Valores inválidos. Ingresa números positivos.', 'Error');
        return;
    end

   
    % Redimensionar imagen
    imagenNueva = imresize(app.ImagenOriginal, [nuevoAlto nuevoAncho]);

    % Verificar si la imagen realmente cambió
    if isempty(app.ImagenProcesada) || ~isequal(imagenNueva, app.ImagenProcesada)
        app.ImagenProcesada = imagenNueva;

        % Mostrar imagen procesada
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);
        
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_2);
        imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada_3);

        % Mostrar características
        infoProcesada = sprintf('Imagen procesada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
        app.LabelInfoProcesada.Text = infoProcesada;

        % Guardar en historial
        app.HistorialOperaciones{end+1} = app.ImagenProcesada;
    end

        end

        % Button pushed function: CargarImagenButton
        function CargarImagenButtonPushed(app, event)
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona una imagen');
    if isequal(file, 0)
        return; % El usuario canceló
    end

    imgPath = fullfile(path, file);
    img = imread(imgPath);
    app.ImagenOriginal = img;

    % Mostrar imagen original
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal);
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_2);
    imshow(app.ImagenOriginal, 'Parent', app.UIAxesOriginal_3);

    % Mostrar características de la imagen original
    infoOriginal = sprintf('Imagen original: %d x %d', size(app.ImagenOriginal,1), size(app.ImagenOriginal,2));
    app.LabelInfoOriginal.Text = infoOriginal;

    % Limpiar imagen procesada y su información
    cla(app.UIAxesProcesada);
    app.LabelInfoProcesada.Text = 'Imagen procesada: -';

    % Reiniciar historial
    app.HistorialOperaciones = {};
    
        end

        % Button pushed function: DeshacerButton
        function DeshacerButtonPushed(app, event)
    if isempty(app.HistorialOperaciones)
        uialert(app.UIFigure, 'No hay operaciones para deshacer.', 'Aviso');
        return;
    end

    % Eliminar la última operación
    app.HistorialOperaciones(end) = [];

    if isempty(app.HistorialOperaciones)
        % Si no hay más historial, volver a la imagen original
        app.ImagenProcesada = app.ImagenOriginal;
    else
        % Restaurar la imagen anterior
        app.ImagenProcesada = app.HistorialOperaciones{end};
    end

    % Mostrar imagen procesada actualizada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Actualizar etiqueta de características
    infoProcesada = sprintf('Imagen procesada: %d x %d', size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

        end

        % Button pushed function: InversinFotogrficaButton
        function InvertirFotograficaButtonPushed(app, event)

% Verificar si hay una imagen cargada
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Invertir la imagen (funciona para RGB y escala de grises)
    app.ImagenProcesada = 255 - app.ImagenOriginal;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características de la imagen procesada
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial para deshacer
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;

        end

        % Button pushed function: InversinBinariaButton
        function vertirBinariaButtonPushed(app, event)

   if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Convertir a escala de grises si es RGB
    if size(app.ImagenOriginal, 3) == 3
        imagenGris = rgb2gray(app.ImagenOriginal);
    else
        imagenGris = app.ImagenOriginal;
    end

    % Convertir a binaria usando un umbral (por ejemplo, 128)
    imagenBinaria = imbinarize(imagenGris, 128/255);

    % Invertir la imagen binaria
    imagenInvertida = ~imagenBinaria;

    % Convertir a formato uint8 (0 y 255)
    app.ImagenProcesada = uint8(imagenInvertida) * 255;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;

        end

        % Button pushed function: AdicinButton
        function AdicionImagenButtonPushed(app, event)
        
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen principal.', 'Error');
        return;
    end

    % Seleccionar segunda imagen
    [file, path] = uigetfile({'*.jpg;*.png;*.bmp;*.tif', 'Archivos de imagen'}, 'Selecciona la segunda imagen');
    if isequal(file, 0)
        return;
    end

    imgPath = fullfile(path, file);
    img2 = imread(imgPath);

    % Verificar que ambas imágenes tengan el mismo tamaño
    if ~isequal(size(app.ImagenOriginal), size(img2))
        uialert(app.UIFigure, 'Las imágenes deben tener el mismo tamaño y tipo.', 'Error');
        return;
    end

    % Realizar la suma y saturar valores mayores a 255
    suma = double(app.ImagenOriginal) + double(img2);
    suma(suma > 255) = 255;
    app.ImagenProcesada = uint8(suma);

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;
    
        end

        % Button pushed function: GuardarImagenButton
        function GuardarImagenButtonPushed(app, event)

    if isempty(app.ImagenProcesada)
        uialert(app.UIFigure, 'No hay imagen procesada para guardar.', 'Aviso');
        return;
    end

    [file, path] = uiputfile({'*.jpg'; '*.png'; '*.bmp'; '*.tif'}, 'Guardar imagen como');
    if isequal(file, 0)
        return; % El usuario canceló
    end

    imwrite(app.ImagenProcesada, fullfile(path, file));
    uialert(app.UIFigure, 'Imagen guardada exitosamente.', 'Éxito');
    
        end

        % Button pushed function: DesplazarImagenButton
        function DesplazarImagenButtonPushed(app, event)
    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar desplazamientos en cada dirección
    prompt = {'Pixeles hacia arriba:', 'Pixeles hacia abajo:', ...
              'Pixeles hacia la izquierda:', 'Pixeles hacia la derecha:'};
    dlgtitle = 'Desplazamiento de imagen';
    dims = [1 35];
    definput = {'0', '0', '0', '0'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    arriba = str2double(answer{1});
    abajo = str2double(answer{2});
    izquierda = str2double(answer{3});
    derecha = str2double(answer{4});

    if any(isnan([arriba, abajo, izquierda, derecha]))
        uialert(app.UIFigure, 'Todos los valores deben ser numéricos.', 'Error');
        return;
    end

    % Calcular desplazamiento total en filas y columnas
    desplazamientoFilas = abajo - arriba;
    desplazamientoColumnas = derecha - izquierda;

    % Aplicar desplazamiento
    imagenDesplazada = circshift(app.ImagenOriginal, [desplazamientoFilas, desplazamientoColumnas]);
    app.ImagenProcesada = imagenDesplazada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;   
        end

        % Button pushed function: CalcularDistanciasButton
        function CalcularDistanciasButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar coordenadas de dos píxeles
    prompt = {'Fila píxel p:', 'Columna píxel p:', 'Fila píxel q:', 'Columna píxel q:'};
    dlgtitle = 'Coordenadas de píxeles';
    dims = [1 35];
    definput = {'1', '1', '10', '10'};
    coords = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(coords)
        return;
    end

    % Convertir a números
    pFila = str2double(coords{1});
    pCol = str2double(coords{2});
    qFila = str2double(coords{3});
    qCol = str2double(coords{4});

    % Validar que estén dentro de la imagen
    [alto, ancho, ~] = size(app.ImagenOriginal);
    if any([pFila, pCol, qFila, qCol] < 1) || ...
       pFila > alto || qFila > alto || ...
       pCol > ancho || qCol > ancho
        uialert(app.UIFigure, 'Las coordenadas deben estar dentro de la imagen.', 'Error');
        return;
    end

    % Calcular distancias
    distEuclidiana = sqrt((pFila - qFila)^2 + (pCol - qCol)^2);
    distD4 = abs(pFila - qFila) + abs(pCol - qCol);
    distD8 = max(abs(pFila - qFila), abs(pCol - qCol));

    % Mostrar resultados
    msg = sprintf(['📏 Distancias entre píxeles:\n\n' ...
                   '🔹 Euclidiana: %.2f píxeles\n' ...
                   '🔹 D4 (Manhattan): %d píxeles\n' ...
                   '🔹 D8 (Chebyshev): %d píxeles'], ...
                   distEuclidiana, distD4, distD8);
    uialert(app.UIFigure, msg, 'Resultados de Distancia');

    % Mostrar puntos y línea en la imagen original
    hold(app.UIAxesOriginal, 'on');
    plot(app.UIAxesOriginal, pCol, pFila, 'ro', 'MarkerSize', 8, 'LineWidth', 2); % Punto p en rojo
    plot(app.UIAxesOriginal, qCol, qFila, 'go', 'MarkerSize', 8, 'LineWidth', 2); % Punto q en verde
    line(app.UIAxesOriginal, [pCol qCol], [pFila qFila], 'Color', 'b', 'LineStyle', '--', 'LineWidth', 1.5); % Línea azul
    hold(app.UIAxesOriginal, 'off');
        end

        % Button pushed function: RotarImagenButton
        function RotarImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Solicitar ángulo de rotación
    prompt = {'Ángulo de rotación (múltiplos de 45°):'};
    dlgtitle = 'Rotar imagen';
    dims = [1 35];
    definput = {'45'};
    answer = inputdlg(prompt, dlgtitle, dims, definput);

    if isempty(answer)
        return;
    end

    angulo = str2double(answer{1});

    % Validar que sea múltiplo de 45
    if isnan(angulo) || mod(angulo, 45) ~= 0
        uialert(app.UIFigure, 'El ángulo debe ser un múltiplo de 45.', 'Error');
        return;
    end

    % Aplicar rotación
    imagenRotada = imrotate(app.ImagenOriginal, angulo);
    app.ImagenProcesada = imagenRotada;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen rotada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;
        end

        % Button pushed function: EspejoButton
        function EspejoImagenButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Opciones de espejo
    opciones = {'Horizontal', 'Vertical', 'Ambos'};
    [seleccion, ok] = listdlg('PromptString', 'Selecciona tipo de espejo:', ...
                              'SelectionMode', 'single', ...
                              'ListString', opciones);

    if ~ok
        return; % Usuario canceló
    end

    % Aplicar operación de espejo
    imagen = app.ImagenOriginal;
    switch opciones{seleccion}
        case 'Horizontal'
            imagenEspejo = fliplr(imagen); % Espejo horizontal
        case 'Vertical'
            imagenEspejo = flipud(imagen); % Espejo vertical
        case 'Ambos'
            imagenEspejo = flipud(fliplr(imagen)); % Espejo horizontal y vertical
    end

    app.ImagenProcesada = imagenEspejo;

    % Mostrar imagen procesada
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen procesada: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;   
        end

        % Button pushed function: MosaicoButton
        function MosaicoEspejoButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Obtener imagen original
    img = app.ImagenOriginal;

    % Crear reflejos
    imgH = fliplr(img);       % Espejo horizontal
    imgV = flipud(img);       % Espejo vertical
    imgHV = flipud(imgH);     % Espejo horizontal + vertical

    % Crear mosaico 2x2
    mosaico = [img, imgH; imgV, imgHV];

    % Redimensionar al tamaño original
    mosaicoFinal = imresize(mosaico, [size(img,1), size(img,2)]);

    % Mostrar imagen procesada
    app.ImagenProcesada = mosaicoFinal;
    imshow(app.ImagenProcesada, 'Parent', app.UIAxesProcesada);

    % Mostrar características
    infoProcesada = sprintf('Imagen mosaico: %d x %d', ...
        size(app.ImagenProcesada,1), size(app.ImagenProcesada,2));
    app.LabelInfoProcesada.Text = infoProcesada;

    % Guardar en historial
    app.HistorialOperaciones{end+1} = app.ImagenProcesada;    
        end

        % Button pushed function: HistogramaButton
        function HistogramaButtonPushed(app, event)

    if isempty(app.ImagenOriginal)
        uialert(app.UIFigure, 'Primero carga una imagen.', 'Error');
        return;
    end

    % Convertir a escala de grises si es RGB
    if size(app.ImagenOriginal, 3) == 3
        imagenGris = rgb2gray(app.ImagenOriginal);
    else
        imagenGris = app.ImagenOriginal;
    end

    % Mostrar histograma en UIAxesHistograma
    cla(app.UIAxesHistograma); % Limpiar eje
    histogram(app.UIAxesHistograma, imagenGris(:), 256);
    title(app.UIAxesHistograma, 'Histograma de la imagen original');
    xlabel(app.UIAxesHistograma, 'Intensidad');
    ylabel(app.UIAxesHistograma, 'Frecuencia');
        end

        % Button pushed function: StrecthButton
        function StretchButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.OperacionesBsicasTab = uitab(app.TabGroup);
            app.OperacionesBsicasTab.Title = ' Operaciones Básicas';
            app.UIAxesOriginal = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesOriginal, 'Imagen Original')
            app.UIAxesOriginal.XTick = [];
            app.UIAxesOriginal.YTick = [];
            app.UIAxesProcesada = uiaxes(app.OperacionesBsicasTab);
            title(app.UIAxesProcesada, 'Imagen Procesada')
            app.UIAxesProcesada.XTick = [];
            app.UIAxesProcesada.YTick = [];
            app.TransformacionesgeomtricasPanel = uipanel(app.OperacionesBsicasTab);
            app.TransformacionesgeomtricasPanel.Title = ' Transformaciones geométricas';
            app.ModificarTamaoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.ModificarTamaoButton.ButtonPushedFcn = createCallbackFcn(app, @ModificarTamanioButtonPushed, true);
            app.ModificarTamaoButton.Text = ' Modificar Tamaño';
            app.DesplazarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.DesplazarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @DesplazarImagenButtonPushed, true);
            app.DesplazarImagenButton.Text = 'Desplazar Imagen';
            app.RotarImagenButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.RotarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @RotarImagenButtonPushed, true);
            app.RotarImagenButton.Text = 'Rotar Imagen';
            app.EspejoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.EspejoButton.ButtonPushedFcn = createCallbackFcn(app, @EspejoImagenButtonPushed, true);
            app.EspejoButton.Text = 'Espejo';
            app.MosaicoButton = uibutton(app.TransformacionesgeomtricasPanel, 'push');
            app.MosaicoButton.ButtonPushedFcn = createCallbackFcn(app, @MosaicoEspejoButtonPushed, true);
            app.MosaicoButton.Text = 'Mosaico';
            app.OperacionesdeintensidadPanel = uipanel(app.OperacionesBsicasTab);
            app.OperacionesdeintensidadPanel.Title = 'Operaciones de intensidad';
            app.InversinFotogrficaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinFotogrficaButton.ButtonPushedFcn = createCallbackFcn(app, @InvertirFotograficaButtonPushed, true);
            app.InversinFotogrficaButton.Text = 'Inversión Fotográfica';
            app.InversinBinariaButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.InversinBinariaButton.ButtonPushedFcn = createCallbackFcn(app, @vertirBinariaButtonPushed, true);
            app.InversinBinariaButton.Text = 'Inversión Binaria';
            app.AdicinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.AdicinButton.ButtonPushedFcn = createCallbackFcn(app, @AdicionImagenButtonPushed, true);
            app.AdicinButton.Text = 'Adición';
            app.SustraccinButton = uibutton(app.OperacionesdeintensidadPanel, 'push');
            app.SustraccinButton.Text = 'Sustracción';
            app.AnlisisdepxelesPanel = uipanel(app.OperacionesBsicasTab);
            app.AnlisisdepxelesPanel.Title = 'Análisis de píxeles';
            app.CalcularDistanciasButton = uibutton(app.AnlisisdepxelesPanel, 'push');
            app.CalcularDistanciasButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularDistanciasButtonPushed, true);
            app.CalcularDistanciasButton.Text = 'Calcular Distancias';
            app.LabelInfoOriginal = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoOriginal.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada = uilabel(app.OperacionesBsicasTab);
            app.LabelInfoProcesada.Text = 'LabelInfoProcesada';
            app.CargarImagenButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.CargarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @CargarImagenButtonPushed, true);
            app.CargarImagenButton.Text = 'Cargar Imagen';
            app.DeshacerButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.DeshacerButton.ButtonPushedFcn = createCallbackFcn(app, @DeshacerButtonPushed, true);
            app.DeshacerButton.Text = 'Deshacer';
            app.GuardarImagenButton = uibutton(app.OperacionesBsicasTab, 'push');
            app.GuardarImagenButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarImagenButtonPushed, true);
            app.GuardarImagenButton.Text = 'Guardar Imagen';
            app.EcualizacinTab = uitab(app.TabGroup);
            app.EcualizacinTab.Title = 'Ecualización';
            app.UIAxesOriginal_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesOriginal_2, 'Imagen Original')
            app.UIAxesOriginal_2.XTick = [];
            app.UIAxesOriginal_2.YTick = [];
            app.UIAxesProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesProcesada_2, 'Imagen Procesada')
            app.UIAxesProcesada_2.XTick = [];
            app.UIAxesProcesada_2.YTick = [];
            app.UIAxesHistograma = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistograma, 'Histograma')
            app.UIAxesHistograma.XTick = [];
            app.UIAxesHistograma.YTick = [];
            app.UIAxesHistogramaProcesada_2 = uiaxes(app.EcualizacinTab);
            title(app.UIAxesHistogramaProcesada_2, 'Histograma')
            app.UIAxesHistogramaProcesada_2.XTick = [];
            app.UIAxesHistogramaProcesada_2.YTick = [];
            app.LabelInfoOriginal_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoOriginal_2.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_2 = uilabel(app.EcualizacinTab);
            app.LabelInfoProcesada_2.Text = 'LabelInfoProcesada';
            app.GuardarImagenButton_2 = uibutton(app.EcualizacinTab, 'push');
            app.GuardarImagenButton_2.Text = 'Guardar Imagen';
            app.EcualizacinPanel = uipanel(app.EcualizacinTab);
            app.EcualizacinPanel.Title = 'Ecualización	';
            app.HistogramaButton = uibutton(app.EcualizacinPanel, 'push');
            app.HistogramaButton.ButtonPushedFcn = createCallbackFcn(app, @HistogramaButtonPushed, true);
            app.HistogramaButton.Text = 'Histograma	';
            app.StrecthButton = uibutton(app.EcualizacinPanel, 'push');
            app.StrecthButton.ButtonPushedFcn = createCallbackFcn(app, @StretchButtonPushed, true);
            app.StrecthButton.Text = 'Strecth';
            app.ShrinkButton = uibutton(app.EcualizacinPanel, 'push');
            app.ShrinkButton.Text = 'Shrink';
            app.EcualizarButton = uibutton(app.EcualizacinPanel, 'push');
            app.EcualizarButton.Text = 'Ecualizar';
            app.DeshacerButton_2 = uibutton(app.EcualizacinTab, 'push');
            app.DeshacerButton_2.Text = 'Deshacer';
            app.UmbralizacinTab = uitab(app.TabGroup);
            app.UmbralizacinTab.Title = 'Umbralización';
            app.UIAxesOriginal_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesOriginal_3, 'Imagen Original')
            app.UIAxesOriginal_3.XTick = [];
            app.UIAxesOriginal_3.YTick = [];
            app.UIAxesProcesada_3 = uiaxes(app.UmbralizacinTab);
            title(app.UIAxesProcesada_3, 'Imagen Procesada')
            app.UIAxesProcesada_3.XTick = [];
            app.UIAxesProcesada_3.YTick = [];
            app.LabelInfoOriginal_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoOriginal_3.Text = 'LabelInfoOriginal';
            app.LabelInfoProcesada_3 = uilabel(app.UmbralizacinTab);
            app.LabelInfoProcesada_3.Text = 'LabelInfoProcesada';
            app.GuardarImagenButton_3 = uibutton(app.UmbralizacinTab, 'push');
            app.GuardarImagenButton_3.Text = 'Guardar Imagen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = P1_7Equipo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end